classdef simc_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SpatialInterferenceMitigationCircuitInterfaceLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        HomeTab                      matlab.ui.container.Tab
        AboutTextAreaLabel           matlab.ui.control.Label
        AboutTextArea                matlab.ui.control.TextArea
        InfoTextAreaLabel            matlab.ui.control.Label
        InfoTextArea                 matlab.ui.control.TextArea
        FilterVoltagesTab            matlab.ui.container.Tab
        UpdateButton                 matlab.ui.control.Button
        TabGroup3                    matlab.ui.container.TabGroup
        Filter1Tab                   matlab.ui.container.Tab
        Channel1KnobLabel            matlab.ui.control.Label
        Channel1Knob                 matlab.ui.control.Knob
        Channel2KnobLabel            matlab.ui.control.Label
        Channel2Knob                 matlab.ui.control.Knob
        Channel3KnobLabel            matlab.ui.control.Label
        Channel3Knob                 matlab.ui.control.Knob
        Channel4KnobLabel            matlab.ui.control.Label
        Channel4Knob                 matlab.ui.control.Knob
        Filter2Tab                   matlab.ui.container.Tab
        Channel1Knob_2Label          matlab.ui.control.Label
        Channel1Knob_2               matlab.ui.control.Knob
        Channel2Knob_2Label          matlab.ui.control.Label
        Channel2Knob_2               matlab.ui.control.Knob
        Channel3Knob_2Label          matlab.ui.control.Label
        Channel3Knob_2               matlab.ui.control.Knob
        Channel4Knob_2Label          matlab.ui.control.Label
        Channel4Knob_2               matlab.ui.control.Knob
        Filter3Tab                   matlab.ui.container.Tab
        Channel1Knob_3Label          matlab.ui.control.Label
        Channel1Knob_3               matlab.ui.control.Knob
        Channel2Knob_3Label          matlab.ui.control.Label
        Channel2Knob_3               matlab.ui.control.Knob
        Channel3Knob_3Label          matlab.ui.control.Label
        Channel3Knob_3               matlab.ui.control.Knob
        Channel4Knob_3Label          matlab.ui.control.Label
        Channel4Knob_3               matlab.ui.control.Knob
        Filter4Tab                   matlab.ui.container.Tab
        Channel1Knob_4Label          matlab.ui.control.Label
        Channel1Knob_4               matlab.ui.control.Knob
        Channel2Knob_4Label          matlab.ui.control.Label
        Channel2Knob_4               matlab.ui.control.Knob
        Channel3Knob_4Label          matlab.ui.control.Label
        Channel3Knob_4               matlab.ui.control.Knob
        Channel4Knob_4Label          matlab.ui.control.Label
        Channel4Knob_4               matlab.ui.control.Knob
        Filter5Tab                   matlab.ui.container.Tab
        Channel1Knob_5Label          matlab.ui.control.Label
        Channel1Knob_5               matlab.ui.control.Knob
        Channel2Knob_5Label          matlab.ui.control.Label
        Channel2Knob_5               matlab.ui.control.Knob
        Channel3Knob_5Label          matlab.ui.control.Label
        Channel3Knob_5               matlab.ui.control.Knob
        Channel4Knob_5Label          matlab.ui.control.Label
        Channel4Knob_5               matlab.ui.control.Knob
        Filter6Tab                   matlab.ui.container.Tab
        Channel1Knob_6Label          matlab.ui.control.Label
        Channel1Knob_6               matlab.ui.control.Knob
        Channel2Knob_6Label          matlab.ui.control.Label
        Channel2Knob_6               matlab.ui.control.Knob
        Channel3Knob_6Label          matlab.ui.control.Label
        Channel3Knob_6               matlab.ui.control.Knob
        Channel4Knob_6Label          matlab.ui.control.Label
        Channel4Knob_6               matlab.ui.control.Knob
        Filter7Tab                   matlab.ui.container.Tab
        Channel1Knob_7Label          matlab.ui.control.Label
        Channel1Knob_7               matlab.ui.control.Knob
        Channel2Knob_7Label          matlab.ui.control.Label
        Channel2Knob_7               matlab.ui.control.Knob
        Channel3Knob_7Label          matlab.ui.control.Label
        Channel3Knob_7               matlab.ui.control.Knob
        Channel4Knob_7Label          matlab.ui.control.Label
        Channel4Knob_7               matlab.ui.control.Knob
        Filter8Tab                   matlab.ui.container.Tab
        Channel1Knob_8Label          matlab.ui.control.Label
        Channel1Knob_8               matlab.ui.control.Knob
        Channel2Knob_8Label          matlab.ui.control.Label
        Channel2Knob_8               matlab.ui.control.Knob
        Channel3Knob_8Label          matlab.ui.control.Label
        Channel3Knob_8               matlab.ui.control.Knob
        Channel4Knob_8Label          matlab.ui.control.Label
        Channel4Knob_8               matlab.ui.control.Knob
        SpatialResponseTab           matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        ViewSwitchLabel              matlab.ui.control.Label
        ViewSwitch                   matlab.ui.control.Switch
        UIAxes2                      matlab.ui.control.UIAxes
        ProcessingResponseLampLabel  matlab.ui.control.Label
        ProcessingResponseLamp       matlab.ui.control.Lamp
    end


    properties (Access = public)
        S; % Create the variable to hold the SPI device
        v0; v1; v2; v3; v4; v5; v6; v7; v8; v9; v10; v11; v12; v13; v14; v15; v16; v17; v18; v19; v20; v21; v22; v23; v24; v25; v26; v27; v28; v29; v30; v31;
        voltages;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.S = spi('aardvark',0,0);
                connect(app.S);
            catch
            %if fn errored out earlier, necessary to disconnect and reconnect.
                msgbox('Unable to Connect to SPI Device!  Make sure device is connected','Error');
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Define the voltages going through each filter
            app.v0 = app.Channel1Knob.Value; app.v1 = app.Channel2Knob.Value; app.v2 = app.Channel3Knob.Value; app.v3 = app.Channel4Knob.Value;
            app.v4 = app.Channel1Knob_2.Value; app.v5 = app.Channel2Knob_2.Value; app.v6 = app.Channel3Knob_2.Value; app.v7 = app.Channel4Knob_2.Value;
            app.v8 = app.Channel1Knob_3.Value; app.v9 = app.Channel2Knob_3.Value; app.v10 = app.Channel3Knob_3.Value; app.v11 = app.Channel4Knob_3.Value;
            app.v12 = app.Channel1Knob_4.Value; app.v13 = app.Channel2Knob_4.Value; app.v14 = app.Channel3Knob_4.Value; app.v15 = app.Channel4Knob_4.Value;
            app.v16 = app.Channel1Knob_5.Value; app.v17 = app.Channel2Knob_5.Value; app.v18 = app.Channel3Knob_5.Value; app.v19 = app.Channel4Knob_5.Value;
            app.v20 = app.Channel1Knob_6.Value; app.v21 = app.Channel2Knob_6.Value; app.v22 = app.Channel3Knob_6.Value; app.v23 = app.Channel4Knob_6.Value;
            app.v24 = app.Channel1Knob_7.Value; app.v25 = app.Channel2Knob_7.Value; app.v26 = app.Channel3Knob_7.Value; app.v27 = app.Channel4Knob_7.Value;
            app.v28 = app.Channel1Knob_8.Value; app.v29 = app.Channel2Knob_8.Value; app.v30 = app.Channel3Knob_8.Value; app.v31 = app.Channel4Knob_8.Value;
            app.voltages = [app.v0 app.v1 app.v2 app.v3 app.v4 app.v5 app.v6 app.v7 app.v8 app.v9 app.v10 app.v11 app.v12 app.v13 app.v14 app.v15 app.v16 app.v17 app.v18 app.v19 app.v20 app.v21 app.v22 app.v23 app.v24 app.v25 app.v26 app.v27 app.v28 app.v29 app.v30 app.v31]; 
            %push voltages through with exception handling
            M = [1 1];
            for voltage = 1:32
                ADDRESS = de2bi(voltage,6,'left-msb');
                try
                    DAC_CODE = de2bi(app.voltages(voltage),16,'left-msb');
                    DATA = [M ADDRESS DAC_CODE];
                    write(app.S,DATA);
                catch
                    alert = msgbox(strcat('Unable to push voltage: ',int2str(voltage),'. Check Device!'),'Error');
                end
            end
            alert = msgbox('Finished pushing to DAC','Completed');

        end

        % Value changed function: ViewSwitch
        function ViewSwitchValueChanged(app, event)
            value = app.ViewSwitch.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SpatialInterferenceMitigationCircuitInterfaceLabel = uilabel(app.UIFigure);
            app.SpatialInterferenceMitigationCircuitInterfaceLabel.Text = 'Spatial Interference Mitigation Circuit Interface';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.AboutTextAreaLabel = uilabel(app.HomeTab);
            app.AboutTextAreaLabel.Text = 'About';
            app.AboutTextArea = uitextarea(app.HomeTab);
            app.AboutTextArea.Value = {'This interface allows the direct control of the voltages being output to the varactors on the Spatial Interference Mitigation Circuit.  These voltages determine where the spatial null is located in the filter.'; ''; 'In order to use this application, navigate to the "Filter Voltages" tab.  Here you will see knobs to insert a voltage for all four varactors of each filter.  This will be an int on the range of 0 to 65,535 [0 to (2^16)-1].  This number corresponds to the 16 bit DAC signal being sent to the board.  Using a 3V reference, the board sends 0V at 0 and 12V at 65,535.  This is then sent through an op-amp chain.  When all 32 channels are correct, press the "Update"  button on the bottom to pulse these voltages to the varactors.'; ''; 'This interface allows the user to view the spatial response in real time if the SIMC is connected.  Under the "Spatial Response" tab, flip the "View" switch in order to generate the spatial response of the circuit.  View the user guide to see how the output must be connected.'; ''; 'Future development will include machine learning integration to assist in providing the DAC signals to place a null at a specific location in space.'};
            app.InfoTextAreaLabel = uilabel(app.HomeTab);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.HomeTab);
            app.InfoTextArea.Value = {'Product of the Advanced Radar Research Center, Norman, Oklahoma.'; 'Software licensed under the GNU GPL v3.  All modifications and distribution must comply.'; 'Author: Caleb Carr | Caleb.M.Carr-1@ou.edu'};
            app.FilterVoltagesTab = uitab(app.TabGroup);
            app.FilterVoltagesTab.Title = 'Filter Voltages';
            app.UpdateButton = uibutton(app.FilterVoltagesTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TabGroup3 = uitabgroup(app.FilterVoltagesTab);
            app.Filter1Tab = uitab(app.TabGroup3);
            app.Filter1Tab.Title = 'Filter 1';
            app.Channel1KnobLabel = uilabel(app.Filter1Tab);
            app.Channel1KnobLabel.Text = 'Channel 1';
            app.Channel1Knob = uiknob(app.Filter1Tab, 'continuous');
            app.Channel1Knob.Limits = [0 65535];
            app.Channel1Knob.MajorTicks = [0 65535];
            app.Channel1Knob.MajorTickLabels = {'0', '65535'};
            app.Channel2KnobLabel = uilabel(app.Filter1Tab);
            app.Channel2KnobLabel.Text = 'Channel 2';
            app.Channel2Knob = uiknob(app.Filter1Tab, 'continuous');
            app.Channel2Knob.Limits = [0 65535];
            app.Channel2Knob.MajorTicks = [0 65535];
            app.Channel2Knob.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel3KnobLabel = uilabel(app.Filter1Tab);
            app.Channel3KnobLabel.Text = 'Channel 3';
            app.Channel3Knob = uiknob(app.Filter1Tab, 'continuous');
            app.Channel3Knob.Limits = [0 65535];
            app.Channel3Knob.MajorTicks = [0 65535];
            app.Channel3Knob.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel4KnobLabel = uilabel(app.Filter1Tab);
            app.Channel4KnobLabel.Text = 'Channel 4';
            app.Channel4Knob = uiknob(app.Filter1Tab, 'continuous');
            app.Channel4Knob.Limits = [0 65535];
            app.Channel4Knob.MajorTicks = [0 65535];
            app.Channel4Knob.MajorTickLabels = {'0', '6.554e+04'};
            app.Filter2Tab = uitab(app.TabGroup3);
            app.Filter2Tab.Title = 'Filter 2';
            app.Channel1Knob_2Label = uilabel(app.Filter2Tab);
            app.Channel1Knob_2Label.Text = 'Channel 1';
            app.Channel1Knob_2 = uiknob(app.Filter2Tab, 'continuous');
            app.Channel1Knob_2.Limits = [0 65535];
            app.Channel1Knob_2.MajorTicks = [0 65535];
            app.Channel1Knob_2.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel2Knob_2Label = uilabel(app.Filter2Tab);
            app.Channel2Knob_2Label.Text = 'Channel 2';
            app.Channel2Knob_2 = uiknob(app.Filter2Tab, 'continuous');
            app.Channel2Knob_2.Limits = [0 65535];
            app.Channel2Knob_2.MajorTicks = [0 65535];
            app.Channel2Knob_2.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel3Knob_2Label = uilabel(app.Filter2Tab);
            app.Channel3Knob_2Label.Text = 'Channel 3';
            app.Channel3Knob_2 = uiknob(app.Filter2Tab, 'continuous');
            app.Channel3Knob_2.Limits = [0 65535];
            app.Channel3Knob_2.MajorTicks = [0 65535];
            app.Channel3Knob_2.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel4Knob_2Label = uilabel(app.Filter2Tab);
            app.Channel4Knob_2Label.Text = 'Channel 4';
            app.Channel4Knob_2 = uiknob(app.Filter2Tab, 'continuous');
            app.Channel4Knob_2.Limits = [0 65535];
            app.Channel4Knob_2.MajorTicks = [0 65535];
            app.Channel4Knob_2.MajorTickLabels = {'0', '6.554e+04'};
            app.Filter3Tab = uitab(app.TabGroup3);
            app.Filter3Tab.Title = 'Filter 3';
            app.Channel1Knob_3Label = uilabel(app.Filter3Tab);
            app.Channel1Knob_3Label.Text = 'Channel 1';
            app.Channel1Knob_3 = uiknob(app.Filter3Tab, 'continuous');
            app.Channel1Knob_3.Limits = [0 65535];
            app.Channel1Knob_3.MajorTicks = [0 65535];
            app.Channel1Knob_3.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel2Knob_3Label = uilabel(app.Filter3Tab);
            app.Channel2Knob_3Label.Text = 'Channel 2';
            app.Channel2Knob_3 = uiknob(app.Filter3Tab, 'continuous');
            app.Channel2Knob_3.Limits = [0 65535];
            app.Channel2Knob_3.MajorTicks = [0 65535];
            app.Channel2Knob_3.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel3Knob_3Label = uilabel(app.Filter3Tab);
            app.Channel3Knob_3Label.Text = 'Channel 3';
            app.Channel3Knob_3 = uiknob(app.Filter3Tab, 'continuous');
            app.Channel3Knob_3.Limits = [0 65535];
            app.Channel3Knob_3.MajorTicks = [0 65535];
            app.Channel3Knob_3.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel4Knob_3Label = uilabel(app.Filter3Tab);
            app.Channel4Knob_3Label.Text = 'Channel 4';
            app.Channel4Knob_3 = uiknob(app.Filter3Tab, 'continuous');
            app.Channel4Knob_3.Limits = [0 65535];
            app.Channel4Knob_3.MajorTicks = [0 65535];
            app.Channel4Knob_3.MajorTickLabels = {'0', '6.554e+04'};
            app.Filter4Tab = uitab(app.TabGroup3);
            app.Filter4Tab.Title = 'Filter 4';
            app.Channel1Knob_4Label = uilabel(app.Filter4Tab);
            app.Channel1Knob_4Label.Text = 'Channel 1';
            app.Channel1Knob_4 = uiknob(app.Filter4Tab, 'continuous');
            app.Channel1Knob_4.Limits = [0 65535];
            app.Channel1Knob_4.MajorTicks = [0 65535];
            app.Channel1Knob_4.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel2Knob_4Label = uilabel(app.Filter4Tab);
            app.Channel2Knob_4Label.Text = 'Channel 2';
            app.Channel2Knob_4 = uiknob(app.Filter4Tab, 'continuous');
            app.Channel2Knob_4.Limits = [0 65535];
            app.Channel2Knob_4.MajorTicks = [0 65535];
            app.Channel2Knob_4.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel3Knob_4Label = uilabel(app.Filter4Tab);
            app.Channel3Knob_4Label.Text = 'Channel 3';
            app.Channel3Knob_4 = uiknob(app.Filter4Tab, 'continuous');
            app.Channel3Knob_4.Limits = [0 65535];
            app.Channel3Knob_4.MajorTicks = [0 65535];
            app.Channel3Knob_4.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel4Knob_4Label = uilabel(app.Filter4Tab);
            app.Channel4Knob_4Label.Text = 'Channel 4';
            app.Channel4Knob_4 = uiknob(app.Filter4Tab, 'continuous');
            app.Channel4Knob_4.Limits = [0 65535];
            app.Channel4Knob_4.MajorTicks = [0 65535];
            app.Channel4Knob_4.MajorTickLabels = {'0', '6.554e+04'};
            app.Filter5Tab = uitab(app.TabGroup3);
            app.Filter5Tab.Title = 'Filter 5';
            app.Channel1Knob_5Label = uilabel(app.Filter5Tab);
            app.Channel1Knob_5Label.Text = 'Channel 1';
            app.Channel1Knob_5 = uiknob(app.Filter5Tab, 'continuous');
            app.Channel1Knob_5.Limits = [0 65535];
            app.Channel1Knob_5.MajorTicks = [0 65535];
            app.Channel1Knob_5.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel2Knob_5Label = uilabel(app.Filter5Tab);
            app.Channel2Knob_5Label.Text = 'Channel 2';
            app.Channel2Knob_5 = uiknob(app.Filter5Tab, 'continuous');
            app.Channel2Knob_5.Limits = [0 65535];
            app.Channel2Knob_5.MajorTicks = [0 65535];
            app.Channel2Knob_5.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel3Knob_5Label = uilabel(app.Filter5Tab);
            app.Channel3Knob_5Label.Text = 'Channel 3';
            app.Channel3Knob_5 = uiknob(app.Filter5Tab, 'continuous');
            app.Channel3Knob_5.Limits = [0 65535];
            app.Channel3Knob_5.MajorTicks = [0 65535];
            app.Channel3Knob_5.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel4Knob_5Label = uilabel(app.Filter5Tab);
            app.Channel4Knob_5Label.Text = 'Channel 4';
            app.Channel4Knob_5 = uiknob(app.Filter5Tab, 'continuous');
            app.Channel4Knob_5.Limits = [0 65535];
            app.Channel4Knob_5.MajorTicks = [0 65535];
            app.Channel4Knob_5.MajorTickLabels = {'0', '6.554e+04'};
            app.Filter6Tab = uitab(app.TabGroup3);
            app.Filter6Tab.Title = 'Filter 6';
            app.Channel1Knob_6Label = uilabel(app.Filter6Tab);
            app.Channel1Knob_6Label.Text = 'Channel 1';
            app.Channel1Knob_6 = uiknob(app.Filter6Tab, 'continuous');
            app.Channel1Knob_6.Limits = [0 65535];
            app.Channel1Knob_6.MajorTicks = [0 65535];
            app.Channel1Knob_6.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel2Knob_6Label = uilabel(app.Filter6Tab);
            app.Channel2Knob_6Label.Text = 'Channel 2';
            app.Channel2Knob_6 = uiknob(app.Filter6Tab, 'continuous');
            app.Channel2Knob_6.Limits = [0 65535];
            app.Channel2Knob_6.MajorTicks = [0 65535];
            app.Channel2Knob_6.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel3Knob_6Label = uilabel(app.Filter6Tab);
            app.Channel3Knob_6Label.Text = 'Channel 3';
            app.Channel3Knob_6 = uiknob(app.Filter6Tab, 'continuous');
            app.Channel3Knob_6.Limits = [0 65535];
            app.Channel3Knob_6.MajorTicks = [0 65535];
            app.Channel3Knob_6.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel4Knob_6Label = uilabel(app.Filter6Tab);
            app.Channel4Knob_6Label.Text = 'Channel 4';
            app.Channel4Knob_6 = uiknob(app.Filter6Tab, 'continuous');
            app.Channel4Knob_6.Limits = [0 65535];
            app.Channel4Knob_6.MajorTicks = [0 65535];
            app.Channel4Knob_6.MajorTickLabels = {'0', '6.554e+04'};
            app.Filter7Tab = uitab(app.TabGroup3);
            app.Filter7Tab.Title = 'Filter 7';
            app.Channel1Knob_7Label = uilabel(app.Filter7Tab);
            app.Channel1Knob_7Label.Text = 'Channel 1';
            app.Channel1Knob_7 = uiknob(app.Filter7Tab, 'continuous');
            app.Channel1Knob_7.Limits = [0 65535];
            app.Channel1Knob_7.MajorTicks = [0 65535];
            app.Channel1Knob_7.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel2Knob_7Label = uilabel(app.Filter7Tab);
            app.Channel2Knob_7Label.Text = 'Channel 2';
            app.Channel2Knob_7 = uiknob(app.Filter7Tab, 'continuous');
            app.Channel2Knob_7.Limits = [0 65535];
            app.Channel2Knob_7.MajorTicks = [0 65535];
            app.Channel2Knob_7.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel3Knob_7Label = uilabel(app.Filter7Tab);
            app.Channel3Knob_7Label.Text = 'Channel 3';
            app.Channel3Knob_7 = uiknob(app.Filter7Tab, 'continuous');
            app.Channel3Knob_7.Limits = [0 65535];
            app.Channel3Knob_7.MajorTicks = [0 65535];
            app.Channel3Knob_7.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel4Knob_7Label = uilabel(app.Filter7Tab);
            app.Channel4Knob_7Label.Text = 'Channel 4';
            app.Channel4Knob_7 = uiknob(app.Filter7Tab, 'continuous');
            app.Channel4Knob_7.Limits = [0 65535];
            app.Channel4Knob_7.MajorTicks = [0 65535];
            app.Channel4Knob_7.MajorTickLabels = {'0', '6.554e+04'};
            app.Filter8Tab = uitab(app.TabGroup3);
            app.Filter8Tab.Title = 'Filter 8';
            app.Channel1Knob_8Label = uilabel(app.Filter8Tab);
            app.Channel1Knob_8Label.Text = 'Channel 1';
            app.Channel1Knob_8 = uiknob(app.Filter8Tab, 'continuous');
            app.Channel1Knob_8.Limits = [0 65535];
            app.Channel1Knob_8.MajorTicks = [0 65535];
            app.Channel1Knob_8.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel2Knob_8Label = uilabel(app.Filter8Tab);
            app.Channel2Knob_8Label.Text = 'Channel 2';
            app.Channel2Knob_8 = uiknob(app.Filter8Tab, 'continuous');
            app.Channel2Knob_8.Limits = [0 65535];
            app.Channel2Knob_8.MajorTicks = [0 65535];
            app.Channel2Knob_8.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel3Knob_8Label = uilabel(app.Filter8Tab);
            app.Channel3Knob_8Label.Text = 'Channel 3';
            app.Channel3Knob_8 = uiknob(app.Filter8Tab, 'continuous');
            app.Channel3Knob_8.Limits = [0 65535];
            app.Channel3Knob_8.MajorTicks = [0 65535];
            app.Channel3Knob_8.MajorTickLabels = {'0', '6.554e+04'};
            app.Channel4Knob_8Label = uilabel(app.Filter8Tab);
            app.Channel4Knob_8Label.Text = 'Channel 4';
            app.Channel4Knob_8 = uiknob(app.Filter8Tab, 'continuous');
            app.Channel4Knob_8.Limits = [0 65535];
            app.Channel4Knob_8.MajorTicks = [0 65535];
            app.Channel4Knob_8.MajorTickLabels = {'0', '6.554e+04'};
            app.SpatialResponseTab = uitab(app.TabGroup);
            app.SpatialResponseTab.Title = 'Spatial Response';
            app.UIAxes = uiaxes(app.SpatialResponseTab);
            title(app.UIAxes, 'Spatial Response (Θ)')
            xlabel(app.UIAxes, 'Θ')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            app.ViewSwitchLabel = uilabel(app.SpatialResponseTab);
            app.ViewSwitchLabel.Text = 'View';
            app.ViewSwitch = uiswitch(app.SpatialResponseTab, 'slider');
            app.ViewSwitch.ValueChangedFcn = createCallbackFcn(app, @ViewSwitchValueChanged, true);
            app.UIAxes2 = uiaxes(app.SpatialResponseTab);
            title(app.UIAxes2, 'Spatial Response (Φ)')
            xlabel(app.UIAxes2, 'Φ')
            ylabel(app.UIAxes2, 'Magnitude (dB)')
            app.ProcessingResponseLampLabel = uilabel(app.SpatialResponseTab);
            app.ProcessingResponseLampLabel.Text = 'Processing Response';
            app.ProcessingResponseLamp = uilamp(app.SpatialResponseTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = simc_interface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end